classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RecordButton                    matlab.ui.control.Button
        AnimalDropDownLabel             matlab.ui.control.Label
        AnimalDropDown                  matlab.ui.control.DropDown
        YourTurnLabel                   matlab.ui.control.Label
        AnimalKingdomLabel              matlab.ui.control.Label
        PlaySoundButton                 matlab.ui.control.Button
        YourResultsPanel                matlab.ui.container.Panel
        MaximumFrequencyEditFieldLabel  matlab.ui.control.Label
        MaximumFrequencyEditField       matlab.ui.control.NumericEditField
        MinimumFrequencyEditFieldLabel  matlab.ui.control.Label
        MinimumFrequencyEditField       matlab.ui.control.NumericEditField
        TonalVarianceEditFieldLabel     matlab.ui.control.Label
        TonalVarianceEditField          matlab.ui.control.NumericEditField
        AnimalResultsPanel              matlab.ui.container.Panel
        MaximumFrequencyEditField_2Label  matlab.ui.control.Label
        MaximumFrequencyEditField_2     matlab.ui.control.NumericEditField
        MinimumFrequencyEditField_2Label  matlab.ui.control.Label
        MinimumFrequencyEditField_2     matlab.ui.control.NumericEditField
        TonalVarianceEditField_2Label   matlab.ui.control.Label
        TonalVarianceEditField_2        matlab.ui.control.NumericEditField
        PlaySoundButton_2               matlab.ui.control.Button
        SignalData                      matlab.ui.control.UIAxes
        AnimalSignalData                matlab.ui.control.UIAxes
        Spectrogram                     matlab.ui.control.UIAxes
        AnimalSpectrogram               matlab.ui.control.UIAxes
        AnimalFace                      matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function printMetrics(app, s, F, isAnimal)
            %% Exploring appropriate threshold percentile
            
            % Try to find a natural threshold for the magnitude of the
            % coefficients
            % close all;
            % figure()
            % hold on
            % histogram(s(:))
            % % add vertical lines corresponding to percentiles
            % perct_vals = prctile(s(:), [50, 75, 80, 90]);
            % ylimits = ylim;
            % for i=1:length(perct_vals)
            %     line([perct_vals(i) perct_vals(i)], [0, ylimits(2)], 'color', 'red')
            % end
            
            % Try looking at the spectrogram with the things below a certain
            % threshold zeroed out
            
            if isAnimal
                perctile_thresh = 20;
            else                    
                perctile_thresh = 99;
            end
            thresh_val = prctile(s(:), perctile_thresh);
            
            s_thresh = s;
            s_thresh(s_thresh <= thresh_val) = 0;            
            
            %% Finding max and min freq hit after thresholding
            
            % Get the max freq with any magnitude left
            [max_vals, inds] = max(s_thresh);
            max_ind = max(inds);
            max_freq = F(max_ind)
            
            % Get the minimum frequency with any magnitude left
            min_val = min(s_thresh(s_thresh>0));
            [row, col] = find(s_thresh == min_val);
            min_freq = F(row);
            
            %% Tracking the row index of the max value in each column
            % Then, compute statistics about it, such as its standard dev
            
            [vals, row_inds] = max(s);
            % figure()
            % plot(row_inds)
            
            max_ind_std = std(row_inds);
            
            % Compute the std in terms of freq
            max_freq_vec = F(row_inds);
            max_freq_std = std(max_freq_vec);
            
            % Do the same calculations, but ignoring entries with no sound
            row_inds_filt = row_inds(row_inds>1);
            max_ind_filt_std = std(row_inds_filt);
            
            max_freq_filt_vec = F(row_inds_filt);
            max_freq_filt_std = std(max_freq_filt_vec);   
            if isAnimal
                app.MaximumFrequencyEditField_2.Value = max_freq;
                app.MinimumFrequencyEditField_2.Value = min_freq;
                app.TonalVarianceEditField_2.Value = max_freq_filt_std;
            else
                app.MaximumFrequencyEditField.Value = max_freq;
                app.MinimumFrequencyEditField.Value = min_freq;
                app.TonalVarianceEditField.Value = max_freq_filt_std;
            end

        end
                
        function plotSignal(app, y, t, isAnimal)
            if isAnimal == 0
            % Plot it raw
                plot(app.SignalData, linspace(0,t,length(y)), y);
            else
                plot(app.AnimalSignalData, linspace(0,t,length(y)), y);
            end                
        end
        
        function [spec, Smin, Smax, T, F, s_init_abs] = plotSpectrogram(app, s, fs, T, w, nfft, Slim, alpha, cmap, cbar, type, isAnimal, t)
            %__________________________________________________________________________________________________________________
            % VALIDATE INPUTS, SET DEFAULTS
            switch nargin
                case 4, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; T=[18,1]; nfft=1024; fs=8000;
                case 5, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; T=[18,1]; nfft=1024; 
                case 6, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; T=[18,1]; 
                case 7, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; 
                case 8, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; 
                case 9, type='per'; cbar=false; cmap='default'; alpha=false;
                case 10, type='per'; cbar=false; cmap='default'; 
                case 11, type='per'; cbar=false; 
                case 12, type='per'; 
                case 13
                otherwise, error('Invalid number of input arguments.');
            end
            if(isstr(s)), [s, fs] = wavread(s); end; % read audio data from file

            Tw = T(1);                          % frame width (ms)
            Ts = T(2);                          % frame shift (ms)
            Nw = round(fs*Tw*0.001);            % frame width (samples)
            Ns = round(fs*Ts*0.001);            % frame shift (samples)
            N  = length(s);                     % length of speech signal (samples)
            Smin = Slim(1);                     % lower normalized dynamic range limit 
            Smax = Slim(2);                     % upper normalized dynamic range limit
            if(isstr(w)), w = str2func(w); end; % obtain window function handle from string input
            %__________________________________________________________________________________________________________________
            % SPEECH PREPROCESSING
            if(islogical(alpha) && alpha), s = filter([1 -0.95],1,s);   % apply a typical preemphasis filter
            elseif(~islogical(alpha)) s = filter(alpha,1,s); end;       % apply custom preemphasis filter
            %__________________________________________________________________________________________________________________
            %__________________________________________________________________________________________________________________
            % GET SPECTROGRAM DATA 
            
            [S,F,T] = spectrogram(s,w(Nw).',Nw-Ns,nfft,fs);            % MATLAB's new spectrogram function
            %__________________________________________________________________________________________________________________
            % SET DYNAMIC RANGE 
            S = abs(S);                         % compute magnitude spectrum 
            s_init_abs = S;
            S = S/max(max(S));                  % normalize magntide spectrum
        %    S(S<eps) = eps;                     % prevent zero spectral magnitude values 
            S = 20*log10(S);                    % compute power spectrum in dB
            spec = S;
            %__________________________________________________________________________________________________________________
            % PLOT AND LABEL RESULTS 
            if isAnimal == 0
                imagesc(app.Spectrogram, T, F, S, [Smin Smax]);
                app.Spectrogram.XLim = [0 N/fs];
                app.Spectrogram.YLim = [0 fs/2];
            else
                imagesc(app.AnimalSpectrogram, T, F, S, [Smin Smax]);
                disp(round(t))
                app.AnimalSpectrogram.XLim = [0 N/fs];
                app.AnimalSpectrogram.YLim = [0 fs/2];
            end
            if(cbar), colorbar('FontSize',6); end
        end
    end


    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.BackgroundColor = 'green';
            app.RecordButton.Text = 'Start Recording';
            recObj = audiorecorder;

%             disp('Start speaking');
            t = 3;
            recordblocking(recObj, t);
%             disp('Done recording');
            app.RecordButton.BackgroundColor = 'red';
            app.RecordButton.Text = 'Stop Recording';
            y = getaudiodata(recObj);
            audiowrite('input_human.wav', y, 8000)

            plotSignal(app, y, t, 0);
            [spec, Smin, Smax, T, F, s_abs] = plotSpectrogram(app, y, 8000, [18 1], @hamming, 256, [-45 -2], false, 'default', false, 'lp', 0,t );
            pause(1)
            printMetrics(app, s_abs, F, 0)
            app.RecordButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.RecordButton.Text = 'Record';            
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            value = app.AnimalDropDown.Value;
         
            if strcmp(value,'Monkey')
                [y, Fs] = audioread('monkey.wav'); 
                pic = imread('monkey.jpg');
            elseif strcmp(value,'Duck')
                [y, Fs] = audioread('duck.wav') ;
                pic = imread('duck.jpg');
            elseif strcmp(value,'Elephant')
                [y, Fs] = audioread('elephant.wav');
                pic = imread('elephant.jpg');
            elseif strcmp(value,'Dog')
                [y, Fs] = audioread('dog.wav');
                pic = imread('dog.jpg');
            end
            imshow(pic,'Parent',app.AnimalFace)
            
            dt = 1/Fs;
            t = (length(y)*dt)-dt;
            plotSignal(app, y, t, 1);
            [spec, Smin, Smax, T, F, s_abs] = plotSpectrogram(app, y(:,1), 44100, [18 1], @hamming, 128, [-45 -2], false, 'default', false, 'lp', 1, t );
            printMetrics(app, s_abs, F, 1)

        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            value = app.AnimalDropDown.Value;
            if strcmp(value,'Monkey')
                [y, Fs] = audioread('monkey.wav');                
            elseif strcmp(value,'Duck')
                [y, Fs]= audioread('duck.wav') ;
            elseif strcmp(value,'Elephant')
                [y, Fs] = audioread('elephant.wav');
            elseif strcmp(value,'Dog')
                [y, Fs] = audioread('dog.wav');
            end
            sound(y,Fs)
        end

        % Button pushed function: PlaySoundButton_2
        function PlaySoundButton_2Pushed(app, event)
            [y, Fs] = audioread('input_human.wav');                
            sound(y,Fs)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.UIFigure);
            app.AnimalDropDown.Items = {'Monkey', 'Duck', 'Elephant', 'Dog'};
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.AnimalDropDown.Value = 'Monkey';
            app.YourTurnLabel = uilabel(app.UIFigure);
            app.YourTurnLabel.Text = 'Your Turn';
            app.AnimalKingdomLabel = uilabel(app.UIFigure);
            app.AnimalKingdomLabel.Text = 'Animal Kingdom';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.YourResultsPanel = uipanel(app.UIFigure);
            app.YourResultsPanel.Title = 'Your Results';
            app.MaximumFrequencyEditFieldLabel = uilabel(app.YourResultsPanel);
            app.MaximumFrequencyEditFieldLabel.Text = 'Maximum Frequency';
            app.MaximumFrequencyEditField = uieditfield(app.YourResultsPanel, 'numeric');
            app.MinimumFrequencyEditFieldLabel = uilabel(app.YourResultsPanel);
            app.MinimumFrequencyEditFieldLabel.Text = 'Minimum Frequency';
            app.MinimumFrequencyEditField = uieditfield(app.YourResultsPanel, 'numeric');
            app.TonalVarianceEditFieldLabel = uilabel(app.YourResultsPanel);
            app.TonalVarianceEditFieldLabel.Text = 'Tonal Variance';
            app.TonalVarianceEditField = uieditfield(app.YourResultsPanel, 'numeric');
            app.AnimalResultsPanel = uipanel(app.UIFigure);
            app.AnimalResultsPanel.Title = 'Animal Results';
            app.MaximumFrequencyEditField_2Label = uilabel(app.AnimalResultsPanel);
            app.MaximumFrequencyEditField_2Label.Text = 'Maximum Frequency';
            app.MaximumFrequencyEditField_2 = uieditfield(app.AnimalResultsPanel, 'numeric');
            app.MinimumFrequencyEditField_2Label = uilabel(app.AnimalResultsPanel);
            app.MinimumFrequencyEditField_2Label.Text = 'Minimum Frequency';
            app.MinimumFrequencyEditField_2 = uieditfield(app.AnimalResultsPanel, 'numeric');
            app.TonalVarianceEditField_2Label = uilabel(app.AnimalResultsPanel);
            app.TonalVarianceEditField_2Label.Text = 'Tonal Variance';
            app.TonalVarianceEditField_2 = uieditfield(app.AnimalResultsPanel, 'numeric');
            app.PlaySoundButton_2 = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButton_2Pushed, true);
            app.PlaySoundButton_2.Text = 'Play Sound';
            app.SignalData = uiaxes(app.UIFigure);
            title(app.SignalData, 'Recorded Sound')
            xlabel(app.SignalData, 'Time (s)')
            ylabel(app.SignalData, 'Data')
            app.AnimalSignalData = uiaxes(app.UIFigure);
            title(app.AnimalSignalData, 'Recorded Sound')
            xlabel(app.AnimalSignalData, 'Time (s)')
            ylabel(app.AnimalSignalData, 'Data')
            app.Spectrogram = uiaxes(app.UIFigure);
            title(app.Spectrogram, 'Spectrogram')
            xlabel(app.Spectrogram, 'Time (s)')
            ylabel(app.Spectrogram, 'Frequency (Hz)')
            app.AnimalSpectrogram = uiaxes(app.UIFigure);
            title(app.AnimalSpectrogram, 'Spectrogram')
            xlabel(app.AnimalSpectrogram, 'Time (s)')
            ylabel(app.AnimalSpectrogram, 'Frequency (Hz)')
            app.AnimalFace = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end